classdef Limites < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GraficadorPanel               matlab.ui.container.Panel
        ParametrosPanel               matlab.ui.container.Panel
        FuncionEditFieldLabel         matlab.ui.control.Label
        FuncionEditField              matlab.ui.control.EditField
        LimiteInferiorEditFieldLabel  matlab.ui.control.Label
        LimiteInferiorEditField       matlab.ui.control.NumericEditField
        LimiteSuperiorEditFieldLabel  matlab.ui.control.Label
        LimiteSuperiorEditField       matlab.ui.control.NumericEditField
        Button                        matlab.ui.control.Button
        PasoEditFieldLabel            matlab.ui.control.Label
        PasoEditField                 matlab.ui.control.NumericEditField
        GraficoPanel                  matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            LimIn=app.LimiteInferiorEditField.Value;
            LimSu=app.LimiteSuperiorEditField.Value;
            Paso=app.PasoEditField.Value;
            Fun=app.FuncionEditField.Value;
            Fun=strrep(Fun,"@(x)"," ");
            x=LimIn:Paso:LimSu;
            y=eval(Fun);
            plot(app.UIAxes,x,y);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GraficadorPanel = uipanel(app.UIFigure);
            app.GraficadorPanel.Title = 'Graficador';
            app.ParametrosPanel = uipanel(app.GraficadorPanel);
            app.ParametrosPanel.Title = 'Parametros';
            app.FuncionEditFieldLabel = uilabel(app.ParametrosPanel);
            app.FuncionEditFieldLabel.Text = 'Funcion';
            app.FuncionEditField = uieditfield(app.ParametrosPanel, 'text');
            app.LimiteInferiorEditFieldLabel = uilabel(app.ParametrosPanel);
            app.LimiteInferiorEditFieldLabel.Text = 'Limite Inferior';
            app.LimiteInferiorEditField = uieditfield(app.ParametrosPanel, 'numeric');
            app.LimiteSuperiorEditFieldLabel = uilabel(app.ParametrosPanel);
            app.LimiteSuperiorEditFieldLabel.Text = 'Limite Superior';
            app.LimiteSuperiorEditField = uieditfield(app.ParametrosPanel, 'numeric');
            app.Button = uibutton(app.ParametrosPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.PasoEditFieldLabel = uilabel(app.ParametrosPanel);
            app.PasoEditFieldLabel.Text = 'Paso';
            app.PasoEditField = uieditfield(app.ParametrosPanel, 'numeric');
            app.GraficoPanel = uipanel(app.GraficadorPanel);
            app.GraficoPanel.Title = 'Grafico';
            app.UIAxes = uiaxes(app.GraficoPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Limites

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end