classdef TareaProgramadaAlfredo2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        IntegracionNumericaapartirdelasReglasdeSimpsonLabel  matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        CalcularButton              matlab.ui.control.Button
        LiminferiorEditFieldLabel   matlab.ui.control.Label
        LiminferiorEditField        matlab.ui.control.NumericEditField
        LimsuperiorEditFieldLabel   matlab.ui.control.Label
        LimsuperiorEditField        matlab.ui.control.NumericEditField
        FuncinEditFieldLabel        matlab.ui.control.Label
        FuncinEditField             matlab.ui.control.EditField
        RegladeinicioDropDownLabel  matlab.ui.control.Label
        RegladeinicioDropDown       matlab.ui.control.DropDown
        SegmentosEditFieldLabel     matlab.ui.control.Label
        SegmentosEditField          matlab.ui.control.NumericEditField
        SolucinEditFieldLabel       matlab.ui.control.Label
        SolucinEditField            matlab.ui.control.NumericEditField
        ErrorEditFieldLabel         matlab.ui.control.Label
        ErrorEditField              matlab.ui.control.NumericEditField
        ExportarButton              matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            %se convierte la funcion
            fun = app.FuncinEditField.Value;
            f=str2func(strcat('@(x) ',fun));  %funciones anonimas,
            %se asigna las variables
            a=app.LiminferiorEditField.Value;
            b=app.LimsuperiorEditField.Value;
            n=app.SegmentosEditField.Value;
            h=(b-a)/n;%Calculo del paso
            m=mod(n,2);%calculo del modulo para ver que metodo utilizar
            s=integral(f,a,b); 
             
             if m ==0  %Calculo de Simpson 1/3 Multiple 
                sumimpar=0;
                sumpar=0;
                for i=1:n-1 %Calculo de los segmentos intermedios y el ciclo para
                    xi=a+h*i;%realizar la serie  hasta  la cantidad de segementos
                    o=mod(i,2);%Otro calculo de modulo para ver si el segmento es 
                    if o~=0%Condicional que me indicara como realizar el calculo
                        %estas variables me acumularan la serie de
                        %evaluaciones en la funcion dependiendo de si es
                        %par o impar
                        sumimpar=sumimpar+4*feval(f,xi); 
                    else
                        sumpar=sumpar+2*feval(f,xi);
                    end
                end
                %Calculo de  Simpson de 1/3 sustituyendo los valores
                %calculados en los ciclos
                sol=h*(feval(f,a)+sumimpar+sumpar+feval(f,b))/3;
                
                app.SolucinEditField.Value=sol;
                
                %Calculo del error 
                error=abs((s-sol)/s)*100;
                app.ErrorEditField.Value=error;

             else  %Calculo para multiple 1/3 y 3/8

                 if app.RegladeinicioDropDown.Value==1 %Para empezar con 1/3
                     sumimpar=0;
                     sumpar=0;
                     sum=0;
                     for i=1:n-4   %hace el for para iniciar con un tercio
                         xi=a+h*i;
                         o=mod(i,2);
                         if o~=0
                             sumimpar=sumimpar+4*feval(f,xi);
                         else 
                             sumpar=sumpar+2*feval(f,xi);
                         end
                     end
                     %Regla compuesta se procede igual sin embargo  con
                     %pequenas variaciones
                     sol1=(b-a)*(feval(f,a)+sumimpar+sumpar)/6;
                     xo=xi; %primer valor para simpson de 3/8
                     
                     for i=n-3:n-1
                         xi=a+h*i;
                         sum=sum+feval(f,xi);
                     end
                     %Regla compuesta 3/8,solo
                     %que se agrega el vaor final
                     sol2=(b-a)*(feval(f,xo)+3*sum+feval(f,b))/8;
                     
                     app.SolucinEditField.Value=sol1+sol2;
                     
                    %Calculo del error 
                    error=abs((s-sol1+sol2)/s)*100;
                    app.ErrorEditField.Value=error;
                 
                 elseif app.RegladeinicioDropDown.Value==2 %Para empezar con 3/8
                     sumimpar=0;
                     sumpar=0;
                     sum=0;
                     for i=1:2
                         xi=a+h*i;
                         o=mod(i,2);
                         sum=sum+feval(f,xi);
                     end
                     %Regla de 3/8,solo
                     %que en vez del valor final se utiliza hasta la
                     %evaluacion que se puede realizar
                     sol1=3*h*(feval(f,a)+3*sum+feval(f,a+h*3))/8;
                     
                     for i=4:n-1
                         
                         if o~=0
                             sumimpar=sumimpar+4*feval(f,xi);
                         else 
                             sumpar=sumpar+2*feval(f,xi);
                         end
                     end
                  %Misma regla 1/3 compuesta ,solo
                  %que se agrega el valor final
                 sol2=(b-a)*(feval(f,a+h*3)+sumimpar+sumpar+feval(f,b))/6;
                 
                 app.SolucinEditField.Value=sol1+sol2;
                 
                 %Calculo del error 
                 error=abs((s-sol1+sol2)/s)*100;
                 app.ErrorEditField.Value=error;
                 end
             end
        end

        % Button pushed function: ExportarButton
        function ExportarButtonPushed(app, event)
            fun = app.FuncinEditField.Value;
            f=str2func(strcat('@(x) ',fun));
            a=app.LiminferiorEditField.Value;
            b=app.LimsuperiorEditField.Value;
            s=integral(f,a,b);
            data = ["Resultado Aritmetico" "Resultado Numerico" "Error";s app.SolucinEditField.Value app.ErrorEditField.Value];%se solicita los datos obtenidos en la tabla
            [file,path]=uiputfile('*.xlsx');%se le da una extension al archivo de destino
            filename = fullfile(path,file);%se crea el archivo
            writematrix(data, filename)%me escribe la tabla  en el  archivo de destino
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.IntegracionNumericaapartirdelasReglasdeSimpsonLabel = uilabel(app.UIFigure);
            app.IntegracionNumericaapartirdelasReglasdeSimpsonLabel.Text = {'Integracion Numerica'; 'a partir de las Reglas de Simpson'};
            app.Panel = uipanel(app.UIFigure);
            app.CalcularButton = uibutton(app.Panel, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.LiminferiorEditFieldLabel = uilabel(app.Panel);
            app.LiminferiorEditFieldLabel.Text = 'Lim inferior: ';
            app.LiminferiorEditField = uieditfield(app.Panel, 'numeric');
            app.LimsuperiorEditFieldLabel = uilabel(app.Panel);
            app.LimsuperiorEditFieldLabel.Text = 'Lim superior:';
            app.LimsuperiorEditField = uieditfield(app.Panel, 'numeric');
            app.FuncinEditFieldLabel = uilabel(app.Panel);
            app.FuncinEditFieldLabel.Text = 'Función: ';
            app.FuncinEditField = uieditfield(app.Panel, 'text');
            app.RegladeinicioDropDownLabel = uilabel(app.Panel);
            app.RegladeinicioDropDownLabel.Text = 'Regla de inicio:';
            app.RegladeinicioDropDown = uidropdown(app.Panel);
            app.RegladeinicioDropDown.Items = {'Simp 1/3', 'Simp 3/8'};
            app.RegladeinicioDropDown.ItemsData = {'1,2', ''};
            app.RegladeinicioDropDown.Value = '1,2';
            app.SegmentosEditFieldLabel = uilabel(app.Panel);
            app.SegmentosEditFieldLabel.Text = ' Segmentos';
            app.SegmentosEditField = uieditfield(app.Panel, 'numeric');
            app.SolucinEditFieldLabel = uilabel(app.Panel);
            app.SolucinEditFieldLabel.Text = 'Solución';
            app.SolucinEditField = uieditfield(app.Panel, 'numeric');
            app.ErrorEditFieldLabel = uilabel(app.Panel);
            app.ErrorEditFieldLabel.Text = 'Error:';
            app.ErrorEditField = uieditfield(app.Panel, 'numeric');
            app.ExportarButton = uibutton(app.Panel, 'push');
            app.ExportarButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarButtonPushed, true);
            app.ExportarButton.Text = 'Exportar ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TareaProgramadaAlfredo2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end