classdef TareaProgramadaAlfredo1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MetodoCerradoBiseccionyMetodoAbiertoNewtonRaphsonPanel  matlab.ui.container.Panel
        ParametrosparaelmetodoPanel   matlab.ui.container.Panel
        FuncionEditFieldLabel         matlab.ui.control.Label
        FuncionEditField              matlab.ui.control.EditField
        XiEditFieldLabel              matlab.ui.control.Label
        XiEditField                   matlab.ui.control.NumericEditField
        XuEditFieldLabel              matlab.ui.control.Label
        XuEditField                   matlab.ui.control.NumericEditField
        ExportarTablaButton           matlab.ui.control.Button
        UITabla                       matlab.ui.control.Table
        ValordeParoEditFieldLabel     matlab.ui.control.Label
        ValordeParoEditField          matlab.ui.control.NumericEditField
        BiseccionporErrorButton       matlab.ui.control.Button
        BiseccionporIteracionButton   matlab.ui.control.Button
        CriteriosdeParoLabel          matlab.ui.control.Label
        NRporIteracionButton          matlab.ui.control.Button
        NRporErrorButton              matlab.ui.control.Button
        ParametrosGraficacionPanel    matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        LimiteInferiorEditFieldLabel  matlab.ui.control.Label
        LimiteInferiorEditField       matlab.ui.control.NumericEditField
        LimiteSuperiorEditFieldLabel  matlab.ui.control.Label
        LimiteSuperiorEditField       matlab.ui.control.NumericEditField
        GraficarButton                matlab.ui.control.Button
        PasoEditFieldLabel            matlab.ui.control.Label
        PasoEditField                 matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            LimIn=app.LimiteInferiorEditField.Value;%solicito el valor inicial 
            LimSu=app.LimiteSuperiorEditField.Value;%solicito el valor final 
            Paso=app.PasoEditField.Value;%solicito el paso de graficacion 
            Fun=app.FuncionEditField.Value;%solicito la funcion 
            Fun=strrep(Fun,"@(x)"," ");%Utilizo este comando para que se pueda hacer la sustitucion
            x=LimIn:Paso:LimSu;%Delimito el  intervalo de x
            y=eval(Fun);%evaluo en la funcion los valores de x 
            plot(app.UIAxes,x,y);%Grafica  la  funcion
        end

        % Callback function
        function SolucionarButtonPushed(app, event)
            
        end

        % Button pushed function: ExportarTablaButton
        function ExportarTablaButtonPushed(app, event)
            data = get(app.UITabla,'data');%se solicita los datos obtenidos en la tabla
            [file,path]=uiputfile('*.xlsx');%se le da una extension al archivo de destino
            filename = fullfile(path,file);%se crea el archivo
            writetable (data, filename)%me escribe la tabla  en el  archivo de destino
        end

        % Button pushed function: BiseccionporErrorButton
        function BiseccionporErrorButtonPushed(app, event)

            syms x;% expresiones simbolicas
            fe=app.FuncionEditField.Value;%solicita  la funcion
            f=str2sym(fe);% cambia la funcion a expresion con simbolos
            xl=app.XiEditField.Value;%solicita el valor  inicial
            xu=app.XuEditField.Value;%solicita el valor  final
            xr=xu-xl; %obtencion de la aproximacion
            fl=subs(f,'x',xl);%evaluacion de valor inicial en la funcion
            fu=subs(f,'x',xu);%evaluacion de valor final en la funcion
            T=[];%crea una matriz vacia
            iter=0; % numero inicial de iteraciones
            ea=1; % error aprox inicial
            es=app.ValordeParoEditField.Value; % error  esperado limite
            while ea>es  % condiciones de paro del loop
                xrold=xr;%guardo el antiguo valor
                xr=(xl+xu)/2;%calculo  el   valor nuevo 
                fr=subs(f,'x',xr);%evaluo el valor   obtenido en la funcion
                check=fu*fr;%guardo en una variable el  valor   del producto de  las dos evaluaciones
                iter=iter+1;%aumento el contador de las  iteraciones
                ea=abs((xr-xrold)/xr);%asignacion del nuevo error
                ff=fl*fr;%guardo en una variable el valor del  producto del otro productode evaluaciones
                
                if check>0%condicion  en la que   uso check 
                    xu=xr;%sustituyo el valor  por la raiz
                    fu=fr;%asigno la evaluacion para proseguir la iteracion
                else
                    xl=xr;%sustituyo el valor  por la raiz
                    fl=fr;%asigno la evaluacion para proseguir la iteracion
                end
                F=[iter xl xu xr fl fr ff ea];%definicion de la matriz con los resutados
                R=[T;F];%definicion de la matriz para la tabla
            end
            Resultados=array2table(R,'VariableNames',{'iter' 'xl' 'xu' 'xr' 'f(xl)' 'f(xr)' 'f(xl)*f(xr)' 'Ea'});
            app.UITabla.Data=Resultados;%en la linea se hae la conversion y esta importa la tabla 

        end

        % Button pushed function: BiseccionporIteracionButton
        function BiseccionporIteracionButtonPushed(app, event)
            
            syms x;% expresiones simbolicas
            fe=app.FuncionEditField.Value;%solicita  la funcion
            f=str2sym(fe);% cambia la funcion a expresion con simbolos
            xl=app.XiEditField.Value;%solicita el valor  inicial
            xu=app.XuEditField.Value;%solicita el valor final
            xr=xu-xl;%obtencion de la diferencia
            fl=subs(f,'x',xl);%evaluacion de valor inicial en la funcion
            fu=subs(f,'x',xu);%evaluacion de valor finalal en la funcion
            T=[];%crea una matriz vacia
            iter=0; % numero inicial iteraciones
            imax=app.ValordeParoEditFiel.Value; % numero max de iteraciones
            counter=0; % contador de iteraciones
            ea=1;% error aprox inicial
            es=1e-6; % error  esperado limite
            while counter<imax && ea>es  % condiciones de paro del loop
                
                xrold=xr;%guardo el antiguo valor
                xr=(xl+xu)/2;%calculo  el   valor nuevo 
                fr=subs(f,'x',xr);%evaluo el valor   obtenido en la funcion
                check=fu*fr;%guardo en una variable el  valor   del producto de  las dos evaluaciones
                iter=iter+1;%aumento el contador de las  iteraciones
                ff=fl*fr;%guardo en una variable el valor del  producto del otro productode evaluaciones
                
                if check>0%condicion  en la que   uso check 
                    xu=xr;%sustituyo el valor  por la raiz
                    fu=fr;%asigno la evaluacion para proseguir la iteracion
                else
                    xl=xr;%sustituyo el valor  por la raiz
                    fl=fr;%asigno la evaluacion para proseguir la iteracion
                end
            F=[iter xl xu xr fl fr ff ea];%definicion de la matriz con los resutados
            R=[T;F];%definicion de la matriz para la tabla
            counter=counter+1;%asignacion del nuevo contador
            ea=abs((xr-xrold)/xr);%asignacion del nuevo error
            end
            Resultados=array2table(R,'VariableNames',{'iter' 'xl' 'xu' 'xr' 'f(xl)' 'f(xr)' 'f(xl)*f(xr)' 'Ea'});
            app.UITabla.Data=Resultados;%en la linea se hae la conversion y esta importa la tabla 
        end

        % Button pushed function: NRporErrorButton
        function NRporErrorButtonPushed(app, event)

            syms x   % expresiones simbolicas
            xl= app.XiEditField.value; %solicita el valor  inicial
            fe=app.FuncionEditField.Value;%solicita  la funcion
            f=str2sym(fe);  % cambia la funcion a expresion con simbolos
            df=diff(f);     % 1 derivada 
            dff=diff(df);    % 2 deriva
            es=app.ValordeParoEditField.Value;    % error  esperado limite
            x1=xl;         % guarda la variable  solicitada
            T=[];       %crea una matriz vacia
            xu='vacio';
            xr='vacio';
            for i=1:50         % numero max de iteraciones
                xa=x1;     % guarda la variable de la raiz anterior
                df_value=subs(df,x,xa);  % evaluacion derivada 1
                dff_value=subs(dff,x,xa); % evaluacion derivada 2
                x1=xa-(df_value/dff_value);   % calculo de la raiz
                ea=abs((x1-xa)/x1);       % Calculo error
                
                F=[i xa xu xr x1 df_val dff_val ea];%definicion de la matriz con los resutados
                R=[T;F];     %definicion de la matriz para la tabla
                if(ea<es)    % condicion de paro con error
                break;     
                end
            Resultados=array2table(R,'VariableNames',{'iter' 'xi' 'xu' 'xr' 'f(xi)' 'df(xi)/dt' 'df2(xl)' 'Ea'});
            app.UITabla.Data=Resultados; %en la linea se hae la conversion y esta importa la tabla 
            end
        end

        % Button pushed function: NRporIteracionButton
        function NRporIteracionButtonPushed(app, event)
            syms x   % expresiones simbolicas
            xl= app.XiEditField.value; %solicita el valor  inicial
            fe=app.FuncionEditField.Value;%solicita  la funcion
            f=str2sym(fe);  % cambia la funcion a expresion con simbolos
            df=diff(f);     %  1 derivada
            dff=diff(df);    % 2 derivada
            es=1e-6;    % erro  limite
            iter=app.ValordeParoEditField.Value; %Valor de iteracion maxima
            x1=xl;          % guarda la variable  solicitada
            xu='vacio';
            xr='vacio';
            
            T=[];   %crea una matriz vacia
            for i=1:iter        % numero max de iteraciones
                xa=x1;     % guarda la variable de la raiz anterior
                df_val=subs(df,x,xa);  % evaluacion derivada 1
                dff_val=subs(dff,x,xa); % evaluacion derivada 2
                x1=xa-(df_val/dff_val);   % calculo de la raiz 
                ea=abs((x1-xa)/x1);       % Calculo error
                
                F=[i xa xu xr x1 df_val dff_val ea];%definicion de la matriz con los resutados
                R=[T;F];    %definicion de la matriz para la tabla
                if(ea<es)    % condicion de paro con error
                break
                end
            Resultados=array2table(R,'VariableNames',{'iter' 'xi' 'xu' 'xr' 'f(xi)' 'df(xi)/dt' 'df2(xl)' 'Ea'});
            app.UITabla.Data=Resultados;%en la linea se hae la conversion y esta importa la tabla 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MetodoCerradoBiseccionyMetodoAbiertoNewtonRaphsonPanel = uipanel(app.UIFigure);
            app.MetodoCerradoBiseccionyMetodoAbiertoNewtonRaphsonPanel.Title = 'Metodo Cerrado Biseccion y Metodo Abierto Newton-Raphson';
            app.ParametrosparaelmetodoPanel = uipanel(app.MetodoCerradoBiseccionyMetodoAbiertoNewtonRaphsonPanel);
            app.ParametrosparaelmetodoPanel.Title = 'Parametros para el metodo';
            app.FuncionEditFieldLabel = uilabel(app.ParametrosparaelmetodoPanel);
            app.FuncionEditFieldLabel.Text = 'Funcion';
            app.FuncionEditField = uieditfield(app.ParametrosparaelmetodoPanel, 'text');
            app.XiEditFieldLabel = uilabel(app.ParametrosparaelmetodoPanel);
            app.XiEditFieldLabel.Text = 'Xi';
            app.XiEditField = uieditfield(app.ParametrosparaelmetodoPanel, 'numeric');
            app.XuEditFieldLabel = uilabel(app.ParametrosparaelmetodoPanel);
            app.XuEditFieldLabel.Text = 'Xu';
            app.XuEditField = uieditfield(app.ParametrosparaelmetodoPanel, 'numeric');
            app.ExportarTablaButton = uibutton(app.ParametrosparaelmetodoPanel, 'push');
            app.ExportarTablaButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarTablaButtonPushed, true);
            app.ExportarTablaButton.Text = 'Exportar Tabla';
            app.UITabla = uitable(app.ParametrosparaelmetodoPanel);
            app.UITabla.ColumnName = {'Iteracion'; 'Xi'; 'Xu'; 'Xr'; 'f(Xi) '; 'f(Xr) o f''(Xi)'; 'f(Xl)*f(Xr) o f''''(Xi)'; 'Ea'};
            app.UITabla.RowName = {};
            app.ValordeParoEditFieldLabel = uilabel(app.ParametrosparaelmetodoPanel);
            app.ValordeParoEditFieldLabel.Text = 'Valor de Paro';
            app.ValordeParoEditField = uieditfield(app.ParametrosparaelmetodoPanel, 'numeric');
            app.BiseccionporErrorButton = uibutton(app.ParametrosparaelmetodoPanel, 'push');
            app.BiseccionporErrorButton.ButtonPushedFcn = createCallbackFcn(app, @BiseccionporErrorButtonPushed, true);
            app.BiseccionporErrorButton.Text = 'Biseccion por Error';
            app.BiseccionporIteracionButton = uibutton(app.ParametrosparaelmetodoPanel, 'push');
            app.BiseccionporIteracionButton.ButtonPushedFcn = createCallbackFcn(app, @BiseccionporIteracionButtonPushed, true);
            app.BiseccionporIteracionButton.Text = 'Biseccion por Iteracion';
            app.CriteriosdeParoLabel = uilabel(app.ParametrosparaelmetodoPanel);
            app.CriteriosdeParoLabel.Text = 'Criterios de Paro';
            app.NRporIteracionButton = uibutton(app.ParametrosparaelmetodoPanel, 'push');
            app.NRporIteracionButton.ButtonPushedFcn = createCallbackFcn(app, @NRporIteracionButtonPushed, true);
            app.NRporIteracionButton.Text = 'NR por Iteracion';
            app.NRporErrorButton = uibutton(app.ParametrosparaelmetodoPanel, 'push');
            app.NRporErrorButton.ButtonPushedFcn = createCallbackFcn(app, @NRporErrorButtonPushed, true);
            app.NRporErrorButton.Text = 'NR por Error';
            app.ParametrosGraficacionPanel = uipanel(app.MetodoCerradoBiseccionyMetodoAbiertoNewtonRaphsonPanel);
            app.ParametrosGraficacionPanel.Title = 'Parametros Graficacion';
            app.UIAxes = uiaxes(app.ParametrosGraficacionPanel);
            title(app.UIAxes, 'Grafica de la funcion')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.LimiteInferiorEditFieldLabel = uilabel(app.ParametrosGraficacionPanel);
            app.LimiteInferiorEditFieldLabel.Text = 'Limite Inferior ';
            app.LimiteInferiorEditField = uieditfield(app.ParametrosGraficacionPanel, 'numeric');
            app.LimiteSuperiorEditFieldLabel = uilabel(app.ParametrosGraficacionPanel);
            app.LimiteSuperiorEditFieldLabel.Text = 'Limite Superior';
            app.LimiteSuperiorEditField = uieditfield(app.ParametrosGraficacionPanel, 'numeric');
            app.GraficarButton = uibutton(app.ParametrosGraficacionPanel, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.PasoEditFieldLabel = uilabel(app.ParametrosGraficacionPanel);
            app.PasoEditFieldLabel.Text = 'Paso';
            app.PasoEditField = uieditfield(app.ParametrosGraficacionPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TareaProgramadaAlfredo1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end